classdef Final_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Switch_System      matlab.ui.control.Switch
        RPMIndicator       Aero.ui.control.RPMIndicator
        txt_Title          matlab.ui.control.Label
        Button_ClosedLoop  matlab.ui.control.Button
        Button_OpenLoop    matlab.ui.control.Button
        Switch_Mode        matlab.ui.control.Switch
        Axis_TimeResponse  matlab.ui.control.UIAxes
    end


    properties (Access = public)
    end

    methods (Access = public)

        function results = OpenLoop_MATLAB_Dahlin_TR(app)
            % run the analysis file
            run("C:\Users\omare\Desktop\Second term\Digital Control Systems\Elevator MATLAB Project\GUI\dahlin_pid_eq.m");

            % Figure Properties
            legend(app.Axis_TimeResponse, 'off')
            title(app.Axis_TimeResponse, 'Open Loop Dahlin Time-Response (MATLAB)');
            xlim(app.Axis_TimeResponse, [0 100])
            ylim(app.Axis_TimeResponse, [-1 1])

            % Clearing variables for real-time plotting
            clear iter u y

            % initialize variables
            N = 100;

            y_1 = 0;
            y_2 = 0;

            u_1 = 0;
            u_2 = 0;

            % start time response sampling and plotting
            for t = 1:N
                iter(t) = t;
                u(t) = square(2 * pi * t / 100);
                y(t) = (num_z(1) * u(t) + num_z(2) * u_1 + num_z(3) * u_2 ...
                    - dnum_z(2) * y_1 - dnum_z(3) * y_2) / dnum_z(1);

                u_2 = u_1;
                u_1 = u(t);

                y_2 = y_1;
                y_1 = y(t);

                plot(app.Axis_TimeResponse, iter, y, 'b', 'linewidth', 4.5)
                plot(app.Axis_TimeResponse, iter, u, '--r', 'linewidth', 4.5)
                drawnow
            end
            legend(app.Axis_TimeResponse, 'Open loop output', 'Desired')


        end

        function results = OpenLoop_Arduino_Dahlin_TR(app)
            % run the analysis file
            run("C:\Users\omare\Desktop\Second term\Digital Control Systems\Elevator MATLAB Project\GUI\dahlin_pid_eq.m");
            uno = serialport('COM8',9600);

            % Figure Properties
            legend(app.Axis_TimeResponse, 'off')
            title(app.Axis_TimeResponse, 'Open Loop Dahlin Time-Response (Arduino)');
            xlim(app.Axis_TimeResponse, [0 100])
            ylim(app.Axis_TimeResponse, [-1 1])

            % Clearing variables for real-time plotting
            clear y u iter

            % initialize variables
            N = 100;

            y_1 = 0;
            y_2 = 0;

            u_1 = 0;
            u_2 = 0;

            for t = 1:N
                iter(t) = t;
                u(t) = read(uno, 1, 'int8'); % reading the input signal
                y(t) = (num_z(1) * u(t) + num_z(2) * u_1 + num_z(3) * u_2 ...
                    - dnum_z(2) * y_1 - dnum_z(3) * y_2) / dnum_z(1);

                u_2 = u_1;
                u_1 = u(t);

                y_2 = y_1;
                y_1 = y(t);

                plot(app.Axis_TimeResponse, iter, y, 'b', 'linewidth', 4.5)
                plot(app.Axis_TimeResponse, iter, u, '--r', 'linewidth', 4.5)
                drawnow
            end
            legend(app.Axis_TimeResponse, 'Open loop output', 'Desired')

        end

        function results = ClosedLoop_MATLAB_Dahlin_TR(app)
            % run the analysis file
            run("C:\Users\omare\Desktop\Second term\Digital Control Systems\Elevator MATLAB Project\GUI\dahlin_pid_eq.m");

            % Figure Properties
            legend(app.Axis_TimeResponse, 'off')
            title(app.Axis_TimeResponse, 'Closed Loop Dahlin Time-Response (MATLAB)');
            xlim(app.Axis_TimeResponse, [0 100])
            ylim(app.Axis_TimeResponse, [-1 1])

            % Clearing variables for real-time plotting
            clear y R iter

            % initialize variables
            N = 100;

            y_1 = 0;
            y_2 = 0;

            u_1 = 0;
            u_2 = 0;
            u_3 = 0;

            e_1 = 0;
            e_2 = 0;
            e_3 = 0;

            % start time response sampling and plotting
            for t = 1:N
                iter(t) = t;
                R(t) = square(2 * pi * t / 100);

                u(t) = (num_pid(2) * e_1 + num_pid(3) * e_2 + num_pid(4) * e_3...
                    - dnum_pid(2) * u_1 - dnum_pid(3) * u_2 - dnum_pid(4) * u_3)...
                    / dnum_pid(1);

                y(t) = (num_z(1) * u(t) + num_z(2) * u_1 + num_z(3) * u_2 ...
                    - dnum_z(2) * y_1 - dnum_z(3) * y_2) / dnum_z(1);

                e(t) = R(t) - y(t);

                y_2 = y_1;
                y_1 = y(t);

                e_3 = e_2;
                e_2 = e_1;
                e_1 = e(t);

                u_3 = u_2;
                u_2 = u_1;
                u_1 = u(t);

                plot(app.Axis_TimeResponse, iter, y, 'b', 'linewidth', 4.5)
                plot(app.Axis_TimeResponse, iter, R, '--r', 'linewidth', 4.5)
                drawnow
            end
            legend(app.Axis_TimeResponse, 'Closed loop output', 'Desired')
        end

        function results = ClosedLoop_Arduino_Dahlin_TR(app)
            % run the analysis file
            run("C:\Users\omare\Desktop\Second term\Digital Control Systems\Elevator MATLAB Project\GUI\dahlin_pid_eq.m");
            clear uno
            uno = serialport('COM8',9600);

            % Figure Properties
            legend(app.Axis_TimeResponse, 'off')
            title(app.Axis_TimeResponse, 'Closed Loop Dahlin Time-Response (Arduino)');
            xlim(app.Axis_TimeResponse, [0 100])
            ylim(app.Axis_TimeResponse, [-1 1])

            % Clearing variables for real-time plotting
            clear y R u iter

            % initialize variables
            N = 100;

            y_1 = 0;
            y_2 = 0;

            u_1 = 0;
            u_2 = 0;
            u_3 = 0;

            % start time response sampling and plotting
            for t = 1:N
                iter(t) = t;
                R(t) = read(uno, 1, "int8");

                u(t) = read(uno, 1, "int16");
                u(t) = u(t) / 100;

                y(t) = (num_z(1) * u(t) + num_z(2) * u_1 + num_z(3) * u_2 ...
                    - dnum_z(2) * y_1 - dnum_z(3) * y_2) / dnum_z(1);

                write(uno, round(y(t) * 100), "int16");

                y_2 = y_1;
                y_1 = y(t);

                u_3 = u_2;
                u_2 = u_1;
                u_1 = u(t);

                plot(app.Axis_TimeResponse, iter, y, 'b', 'linewidth', 4.5)
                plot(app.Axis_TimeResponse, iter, R, '--r', 'linewidth', 4.5)
                drawnow
            end
            legend(app.Axis_TimeResponse, 'Closed loop output', 'Desired')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_ClosedLoop
        function Button_ClosedLoopPushed(app, event)
            cla(app.Axis_TimeResponse)

            switch app.Switch_Mode.Value
                case 'ARDUINO'
                    switch app.Switch_System.Value
                        case 'Dahlin'
                            ClosedLoop_Arduino_Dahlin_TR(app);

                        case 'Digital'
                    end

                case 'MATLAB'
                    clear uno
                    switch app.Switch_System.Value
                        case 'Dahlin'
                            ClosedLoop_MATLAB_Dahlin_TR(app);

                        case 'Digital'
                    end
            end
        end

        % Button pushed function: Button_OpenLoop
        function Button_OpenLoopPushed(app, event)
            cla(app.Axis_TimeResponse)

            switch app.Switch_Mode.Value
                case 'ARDUINO'
                    uno = serialport('COM8',9600);
                    switch app.Switch_System.Value
                        case 'Dahlin'
                            OpenLoop_Arduino_Dahlin_TR(app);

                        case 'Digital'
                    end

                case 'MATLAB'
                    clear uno
                    switch app.Switch_System.Value
                        case 'Dahlin'

                            OpenLoop_MATLAB_Dahlin_TR(app);
                        case 'Digital'
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axis_TimeResponse = uiaxes(app.UIFigure);
            title(app.Axis_TimeResponse, 'Time Response')
            xlabel(app.Axis_TimeResponse, 'Time(s)')
            ylabel(app.Axis_TimeResponse, 'Output')
            zlabel(app.Axis_TimeResponse, 'Z')
            app.Axis_TimeResponse.Tag = 'fig_closed';
            app.Switch_Mode = uiswitch(app.UIFigure, 'slider');
            app.Switch_Mode.Items = {'MATLAB', 'ARDUINO'};
            app.Switch_Mode.Value = 'MATLAB';
            app.Button_OpenLoop = uibutton(app.UIFigure, 'push');
            app.Button_OpenLoop.ButtonPushedFcn = createCallbackFcn(app, @Button_OpenLoopPushed, true);
            app.Button_OpenLoop.Tag = 'btn_';
            app.Button_OpenLoop.Text = 'Open Loop';
            app.Button_ClosedLoop = uibutton(app.UIFigure, 'push');
            app.Button_ClosedLoop.ButtonPushedFcn = createCallbackFcn(app, @Button_ClosedLoopPushed, true);
            app.Button_ClosedLoop.Text = 'Closed Loop';
            app.txt_Title = uilabel(app.UIFigure);
            app.txt_Title.Text = 'Welcome to Our Elevator''s Control System GUI';
            app.RPMIndicator = uiaerorpm(app.UIFigure);
            app.RPMIndicator.RPM = 10;
            app.Switch_System = uiswitch(app.UIFigure, 'slider');
            app.Switch_System.Items = {'Digital', 'Dahlin'};
            app.Switch_System.Value = 'Dahlin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end